classdef PVC_PAC_AF_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes_1                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        leftButton              matlab.ui.control.Button
        rigthButton             matlab.ui.control.Button
        curentnumEditField      matlab.ui.control.NumericEditField
        backupbButton           matlab.ui.control.Button
        savesButton             matlab.ui.control.Button
        filenameEditFieldLabel  matlab.ui.control.Label
        filenameEditField       matlab.ui.control.EditField
        labelEditFieldLabel     matlab.ui.control.Label
        labelEditField          matlab.ui.control.EditField
        netpredEditFieldLabel   matlab.ui.control.Label
        netpredEditField        matlab.ui.control.EditField
        leadSpinner_3           matlab.ui.control.Spinner
        leadSpinner_2           matlab.ui.control.Spinner
        leadSpinner_1           matlab.ui.control.Spinner
        patoButtonGroup         matlab.ui.container.ButtonGroup
        PVC1Button              matlab.ui.control.RadioButton
        PAC2Button              matlab.ui.control.RadioButton
        AF3Button               matlab.ui.control.RadioButton
        lead1zoompButton        matlab.ui.control.Button
        lead1zoommButton        matlab.ui.control.Button
        lead2zoompButton        matlab.ui.control.Button
        lead2zoommButton        matlab.ui.control.Button
        lead3zoompButton        matlab.ui.control.Button
        lead3zoommButton        matlab.ui.control.Button
        maxnumEditField         matlab.ui.control.NumericEditField
        Slider                  matlab.ui.control.Slider
        Button                  matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        UIAxes_4                matlab.ui.control.UIAxes
        leadSpinner_4           matlab.ui.control.Spinner
        lead4zoompButton        matlab.ui.control.Button
        lead4zoommButton        matlab.ui.control.Button
        rangesecSpinnerLabel    matlab.ui.control.Label
        rangesecSpinner         matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        data_dir
        net_prediction_dir
        output_dir
        backup_dir
        
        fs
        min_max_perc
        min_max_multiplier_1
        min_max_multiplier_2
        min_max_multiplier_3
        min_max_multiplier_4
        
        signal_names
        net_prediction_names
        output_names
        
        plot1_1
        plot1_2
        plot1_3
        plot1_4
        plot2_1
        plot2_2
        plot2_3
        plot2_4
        plot3_1
        plot3_2
        plot3_3
        plot3_4
        
        plot33_1
        plot33_2
        plot33_3
        plot33_4


        lead1_min_max
        lead2_min_max
        lead3_min_max
        lead4_min_max
        
        result_positions_1
        result_positions_2
        result_positions_3
        
        range_line_1
        range_line_2
        
        signal
        result_name
        
        toolbar_selected
        
        zoom_factor
        
    end
    
    methods (Access = public)
        
        function reload_data(app)
    
  
            app.min_max_multiplier_1=1.2;
            app.min_max_multiplier_2=1.2;
            app.min_max_multiplier_3=1.2;
            app.min_max_multiplier_4=1.2;
            
            app.signal=load(app.signal_names{app.curentnumEditField.Value});
            app.signal=app.signal.val;
            
            try
                pato=app.get_pato(replace(app.signal_names{app.curentnumEditField.Value},'.mat','.hea'));
                app.labelEditField.Value=pato;
            catch
                app.labelEditField.Value='n/a';
            end
            try
                pato_net=app.get_pato_net(app.net_prediction_names{app.curentnumEditField.Value});
                app.netpredEditField.Value=pato_net;
            catch
                app.netpredEditField.Value='n/a';
            end


            [~,name,~] = fileparts(app.signal_names{app.curentnumEditField.Value});
            app.filenameEditField.Value=name;
            
            app.result_name=app.output_names{app.curentnumEditField.Value};

            app.Slider.Limits=[-0.2,max(size(app.signal,2)/app.fs-app.rangesecSpinner.Value+0.2,1)];
            app.Slider.Value=0;
            
            app.UIAxes_1.Toolbar.SelectionChangedFcn=@app.tbar_change;       
            app.UIAxes_2.Toolbar.SelectionChangedFcn=@app.tbar_change;
            app.UIAxes_3.Toolbar.SelectionChangedFcn=@app.tbar_change;
            app.UIAxes_4.Toolbar.SelectionChangedFcn=@app.tbar_change;
            
            if isfile(app.result_name)
                tmp=load(app.result_name);
                app.result_positions_1=tmp.result_positions_PVC;
                app.result_positions_2=tmp.result_positions_PAC;
                app.result_positions_3=tmp.result_positions_AF;
            else
                app.result_positions_1=[];
                app.result_positions_2=[];
                app.result_positions_3=[];
            end
            
            app.lead1_min_max = [prctile(app.signal(app.leadSpinner_1.Value,:),app.min_max_perc) prctile(app.signal(app.leadSpinner_1.Value,:),100-app.min_max_perc)];
            app.lead2_min_max = [prctile(app.signal(app.leadSpinner_2.Value,:),app.min_max_perc) prctile(app.signal(app.leadSpinner_2.Value,:),100-app.min_max_perc)];
            app.lead3_min_max = [prctile(app.signal(app.leadSpinner_3.Value,:),app.min_max_perc) prctile(app.signal(app.leadSpinner_3.Value,:),100-app.min_max_perc)];
            app.lead4_min_max = [prctile(app.signal(app.leadSpinner_4.Value,:),app.min_max_perc) prctile(app.signal(app.leadSpinner_4.Value,:),100-app.min_max_perc)];
            
            
            app.plot_init()
            
            app.plot_positions_1()
            app.plot_positions_2()
            app.plot_positions_3()
            
            app.update_limits_x()
            app.update_limits_y()
            
        end
        

        function update_limits_x(app)
            app.UIAxes_1.XLim=[app.Slider.Value,app.Slider.Value+app.rangesecSpinner.Value];
            app.UIAxes_2.XLim=[app.Slider.Value,app.Slider.Value+app.rangesecSpinner.Value];
            app.UIAxes_3.XLim=[app.Slider.Value,app.Slider.Value+app.rangesecSpinner.Value];
            
            hold(app.UIAxes_4, 'on')
            try delete(app.range_line_1);end
            try delete(app.range_line_2);end
            app.range_line_1=plot(app.UIAxes_4,[app.Slider.Value,app.Slider.Value], app.UIAxes_4.YLim,'r');
            app.range_line_2=plot(app.UIAxes_4,[app.Slider.Value+app.rangesecSpinner.Value,app.Slider.Value+app.rangesecSpinner.Value], app.UIAxes_4.YLim,'r');
            hold(app.UIAxes_4, 'off')
            
            app.UIAxes_4.XLim=[-0.2,size(app.signal,2)/app.fs+0.2];
        end
        
        function update_limits_y(app)

            app.UIAxes_1.YLim=app.lead1_min_max*app.min_max_multiplier_1;
            app.UIAxes_2.YLim=app.lead2_min_max*app.min_max_multiplier_2;
            app.UIAxes_3.YLim=app.lead3_min_max*app.min_max_multiplier_3;
            app.UIAxes_4.YLim=app.lead4_min_max*app.min_max_multiplier_4;
        end
        
        function plot_init(app)
            plot(app.UIAxes_1,(1:length(app.signal(app.leadSpinner_1.Value,:)))/app.fs,app.signal(app.leadSpinner_1.Value,:));
            plot(app.UIAxes_2,(1:length(app.signal(app.leadSpinner_2.Value,:)))/app.fs,app.signal(app.leadSpinner_2.Value,:));
            plot(app.UIAxes_3,(1:length(app.signal(app.leadSpinner_3.Value,:)))/app.fs,app.signal(app.leadSpinner_3.Value,:));
            plot(app.UIAxes_4,(1:length(app.signal(app.leadSpinner_4.Value,:)))/app.fs,app.signal(app.leadSpinner_4.Value,:));
        end
        
        function plot_positions_1(app)
            
            
            hold(app.UIAxes_1, 'on')
            hold(app.UIAxes_2, 'on')
            hold(app.UIAxes_3, 'on')
            hold(app.UIAxes_4, 'on')
            
            try delete(app.plot1_1);end
            try delete(app.plot1_2);end
            try delete(app.plot1_3);end
            try delete(app.plot1_4);end


            app.plot1_1=plot(app.UIAxes_1,app.result_positions_1/app.fs,app.signal(app.leadSpinner_1.Value,round(app.result_positions_1)),'*','Color',app.PVC1Button.FontColor);
            app.plot1_2=plot(app.UIAxes_2,app.result_positions_1/app.fs,app.signal(app.leadSpinner_2.Value,round(app.result_positions_1)),'*','Color',app.PVC1Button.FontColor);
            app.plot1_3=plot(app.UIAxes_3,app.result_positions_1/app.fs,app.signal(app.leadSpinner_3.Value,round(app.result_positions_1)),'*','Color',app.PVC1Button.FontColor);
            app.plot1_4=plot(app.UIAxes_4,app.result_positions_1/app.fs,app.signal(app.leadSpinner_4.Value,round(app.result_positions_1)),'*','Color',app.PVC1Button.FontColor);
            
            
            hold(app.UIAxes_1, 'off')
            hold(app.UIAxes_2, 'off')
            hold(app.UIAxes_3, 'off')
            hold(app.UIAxes_4, 'off')
            
        end
        function plot_positions_2(app)
            
            hold(app.UIAxes_1, 'on')
            hold(app.UIAxes_2, 'on')
            hold(app.UIAxes_3, 'on')
            hold(app.UIAxes_4, 'on')
            
            try delete(app.plot2_1);end
            try delete(app.plot2_2);end
            try delete(app.plot2_3);end
            try delete(app.plot2_4);end

            app.plot2_1=plot(app.UIAxes_1,app.result_positions_2/app.fs,app.signal(app.leadSpinner_1.Value,round(app.result_positions_2)),'*','Color',app.PAC2Button.FontColor);
            app.plot2_2=plot(app.UIAxes_2,app.result_positions_2/app.fs,app.signal(app.leadSpinner_2.Value,round(app.result_positions_2)),'*','Color',app.PAC2Button.FontColor);
            app.plot2_3=plot(app.UIAxes_3,app.result_positions_2/app.fs,app.signal(app.leadSpinner_3.Value,round(app.result_positions_2)),'*','Color',app.PAC2Button.FontColor);
            app.plot2_4=plot(app.UIAxes_4,app.result_positions_2/app.fs,app.signal(app.leadSpinner_4.Value,round(app.result_positions_2)),'*','Color',app.PAC2Button.FontColor);
            

            hold(app.UIAxes_1, 'off')
            hold(app.UIAxes_2, 'off')
            hold(app.UIAxes_3, 'off')
            hold(app.UIAxes_4, 'off')
        end
        
        function plot_positions_3(app)
            hold(app.UIAxes_1, 'on')
            hold(app.UIAxes_2, 'on')
            hold(app.UIAxes_3, 'on')
            hold(app.UIAxes_4, 'on')

            try delete(app.plot3_1);end
            try delete(app.plot3_2);end
            try delete(app.plot3_3);end
            try delete(app.plot3_4);end
            
            
            try delete(app.plot33_1);end
            try delete(app.plot33_2);end
            try delete(app.plot33_3);end
            try delete(app.plot33_4);end
            

            app.plot3_1=plot(app.UIAxes_1,app.result_positions_3/app.fs,app.signal(app.leadSpinner_1.Value,round(app.result_positions_3)),'*','Color',app.AF3Button.FontColor);
            app.plot3_2=plot(app.UIAxes_2,app.result_positions_3/app.fs,app.signal(app.leadSpinner_2.Value,round(app.result_positions_3)),'*','Color',app.AF3Button.FontColor);
            app.plot3_3=plot(app.UIAxes_3,app.result_positions_3/app.fs,app.signal(app.leadSpinner_3.Value,round(app.result_positions_3)),'*','Color',app.AF3Button.FontColor);
            app.plot3_4=plot(app.UIAxes_4,app.result_positions_3/app.fs,app.signal(app.leadSpinner_4.Value,round(app.result_positions_3)),'*','Color',app.AF3Button.FontColor);
            
            
            tmpx=app.result_positions_3/app.fs;
            tmpy=app.signal(app.leadSpinner_1.Value,round(app.result_positions_3));
            tmpxx=[];
            tmpyy=[];
            if mod(length(tmpx),2)==0
                for k=1:2:length(tmpx)
                    tmpxx=[tmpxx;[tmpx(k),tmpx(k+1)]];
                    tmpyy=[tmpyy;[tmpy(k),tmpy(k+1)]];
                end
                app.plot33_1=line(app.UIAxes_1,tmpxx',tmpyy','Color',app.AF3Button.FontColor);
            end
            
            
            tmpx=app.result_positions_3/app.fs;
            tmpy=app.signal(app.leadSpinner_2.Value,round(app.result_positions_3));
            tmpxx=[];
            tmpyy=[];
            if mod(length(tmpx),2)==0
                for k=1:2:length(tmpx)
                    tmpxx=[tmpxx;[tmpx(k),tmpx(k+1)]];
                    tmpyy=[tmpyy;[tmpy(k),tmpy(k+1)]];
                end
                app.plot33_2=line(app.UIAxes_2,tmpxx',tmpyy','Color',app.AF3Button.FontColor);
            end
            
            tmpx=app.result_positions_3/app.fs;
            tmpy=app.signal(app.leadSpinner_3.Value,round(app.result_positions_3));
            tmpxx=[];
            tmpyy=[];
            if mod(length(tmpx),2)==0
                for k=1:2:length(tmpx)
                    tmpxx=[tmpxx;[tmpx(k),tmpx(k+1)]];
                    tmpyy=[tmpyy;[tmpy(k),tmpy(k+1)]];
                end
                app.plot33_3=line(app.UIAxes_3,tmpxx',tmpyy','Color',app.AF3Button.FontColor);
            end
            
            
            tmpx=app.result_positions_3/app.fs;
            tmpy=app.signal(app.leadSpinner_4.Value,round(app.result_positions_3));
            tmpxx=[];
            tmpyy=[];
            if mod(length(tmpx),2)==0
                for k=1:2:length(tmpx)
                    tmpxx=[tmpxx;[tmpx(k),tmpx(k+1)]];
                    tmpyy=[tmpyy;[tmpy(k),tmpy(k+1)]];
                end
                app.plot33_4=line(app.UIAxes_4,tmpxx',tmpyy','Color',app.AF3Button.FontColor);
            end


            hold(app.UIAxes_1, 'off')
            hold(app.UIAxes_2, 'off')
            hold(app.UIAxes_3, 'off')
            hold(app.UIAxes_4, 'off')
        end
        
        
        function pato = get_pato(app,hea_name)
            
            pato='?';
            
            fid = fopen(hea_name);
            tline = fgetl(fid);
            while ischar(tline)
                if contains(tline, '#Dx:')
                    pato=tline;
                end
                tline = fgetl(fid);

            end
            fclose(fid);
            
        end
        function pato = get_pato_net(app,csv_name)
            
            table = readtable(csv_name,'PreserveVariableNames',1);
            patos=table.Properties.VariableNames;
            patos=patos(table{1,:}>0);
            pato=join(patos{:},',');


        end
        
        
        function tbar_change(app,src,event)
            state = event.Selection.Value; % ToolBarStateButton has a "Value of "on" or "off"
            % if selected, turn off line_click callback
            if state == 'on'
               app.toolbar_selected=1;
               
            % if not selected, turn back on line_click
            else
               app.toolbar_selected=0;
            end
            
        end


        function save_res(app)
            
            
            result_positions_PVC=app.result_positions_1;
            result_positions_PAC=app.result_positions_2;
            result_positions_AF=app.result_positions_3;
            save(app.output_names{app.curentnumEditField.Value},'result_positions_PVC','result_positions_PAC','result_positions_AF')
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_dir, net_prediction_dir, output_dir, backup_dir)
            app.data_dir=data_dir;
            app.net_prediction_dir=net_prediction_dir;
            app.output_dir=output_dir;
            app.backup_dir=backup_dir;
            
            
            app.zoom_factor=1.2;
            app.fs=500;
            app.min_max_perc=1;

            
            app.signal_names=subdir([app.data_dir '/*.mat']);
            app.signal_names={app.signal_names.name};
            app.net_prediction_names={};
            app.output_names={};
            for k=1:length(app.signal_names)
                
                [filepath,name_data,ext] = fileparts(app.signal_names{k});
                
                app.net_prediction_names=[app.net_prediction_names,[app.net_prediction_dir '/' name_data '.csv']];
                app.output_names=[app.output_names,[app.output_dir '/' name_data '_position_labels.mat']];
            end
            
            
            app.maxnumEditField.Value=length(app.signal_names);
            app.curentnumEditField.Limits=[1 length(app.signal_names)];
            
            addpath('npy-matlab') 
            
            app.toolbar_selected=0;
            
            app.reload_data()
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.update_limits_x()
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            tmp=app.Slider.Value-app.rangesecSpinner.Value/3;
            tmp(tmp<app.Slider.Limits(1))=app.Slider.Limits(1);
            app.Slider.Value=tmp;
            app.update_limits_x()
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            tmp=app.Slider.Value+app.rangesecSpinner.Value/3;
            tmp(tmp>app.Slider.Limits(2))=app.Slider.Limits(2);
            app.Slider.Value=tmp;
            app.update_limits_x()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            ax=event.Source.CurrentAxes;
            position=ax.CurrentPoint(1,[2,1]);
            
            positiony=position(1);
            position=round(position(2)*app.fs);
            
            if position<1
                position=1;
            end
            if position>size(app.signal,2)
                position=size(app.signal,2);
            end
            
            if positiony<ax.YLim(1)||positiony>ax.YLim(2) 
                return;
            end
            if app.toolbar_selected
                return;
            end

            
            if strcmp(event.Source.SelectionType,'normal')


                if app.PVC1Button.Value
                    app.result_positions_1=[app.result_positions_1,position];
                    app.plot_positions_1()
                end
                if app.PAC2Button.Value
                    app.result_positions_2=[app.result_positions_2,position];
                    app.plot_positions_2()
                end
                if app.AF3Button.Value
                    app.result_positions_3=[app.result_positions_3,position];
                    app.plot_positions_3()
                end
                
            elseif strcmp(event.Source.SelectionType,'alt')
                
                dists=abs(app.result_positions_1-position);
                if ~isempty(app.result_positions_1)
                [dist1,min_dist_arg1]=min(dists);
                else
                    dist1=Inf;
                    min_dist_arg1=[];
                end
                
                dists=abs(app.result_positions_2-position);
                if ~isempty(app.result_positions_2)
                [dist2,min_dist_arg2]=min(dists);
                else
                    dist2=Inf;
                    min_dist_arg2=[];
                end
                
                dists=abs(app.result_positions_3-position);
                if ~isempty(app.result_positions_3)
                [dist3,min_dist_arg3]=min(dists);
                else
                    dist3=Inf;
                    min_dist_arg3=[];
                end
                
                [dist,min_dist_arg]=min([dist1,dist2,dist3]);
                
                if min_dist_arg==1 && dist<500
                    app.result_positions_1(min_dist_arg1) =[];
                    app.plot_positions_1()
                end
                if min_dist_arg==2 && dist<500
                    app.result_positions_2(min_dist_arg2) =[];
                    app.plot_positions_2()
                end
                if min_dist_arg==3 && dist<500
                    app.result_positions_3(min_dist_arg3) =[];
                    app.plot_positions_3()
                end
            end
            
            
        end

        % Value changed function: curentnumEditField
        function curentnumEditFieldValueChanged(app, event)
            app.save_res()
            app.reload_data()
        end

        % Button pushed function: rigthButton
        function rigthButtonPushed(app, event)
            app.save_res()
            app.curentnumEditField.Value=app.curentnumEditField.Value+1;
            app.reload_data()
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            app.save_res()
            app.curentnumEditField.Value=app.curentnumEditField.Value-1;
            app.reload_data()
        end

        % Button pushed function: lead1zoompButton
        function lead1zoompButtonPushed(app, event)
            app.min_max_multiplier_1=app.min_max_multiplier_1/app.zoom_factor;
            app.update_limits_y();
        end

        % Button pushed function: lead1zoommButton
        function lead1zoommButtonPushed(app, event)
            app.min_max_multiplier_1=app.min_max_multiplier_1*app.zoom_factor;
            app.update_limits_y();
        end

        % Button pushed function: lead2zoompButton
        function lead2zoompButtonPushed(app, event)
            app.min_max_multiplier_2=app.min_max_multiplier_2/app.zoom_factor;
            app.update_limits_y();
        end

        % Button pushed function: lead2zoommButton
        function lead2zoommButtonPushed(app, event)
            app.min_max_multiplier_2=app.min_max_multiplier_2*app.zoom_factor;
            app.update_limits_y()
        end

        % Button pushed function: lead3zoompButton
        function lead3zoompButtonPushed(app, event)
            app.min_max_multiplier_3=app.min_max_multiplier_3/app.zoom_factor;
            app.update_limits_y()
        end

        % Button pushed function: lead3zoommButton
        function lead3zoommButtonPushed(app, event)
            app.min_max_multiplier_3=app.min_max_multiplier_3*app.zoom_factor;
            app.update_limits_y()
        end

        % Button pushed function: lead4zoompButton
        function lead4zoompButtonPushed(app, event)
            app.min_max_multiplier_4=app.min_max_multiplier_4/app.zoom_factor;
            app.update_limits_y()
        end

        % Button pushed function: lead4zoommButton
        function lead4zoommButtonPushed(app, event)
            app.min_max_multiplier_4=app.min_max_multiplier_4*app.zoom_factor;
            app.update_limits_y()
        end

        % Value changed function: leadSpinner_1
        function leadSpinner_1ValueChanged(app, event)
            app.plot_init()
            
            app.plot_positions_1()
            app.plot_positions_2()
            app.plot_positions_3()
            
            app.update_limits_x()
            app.update_limits_y()
            
        end

        % Value changed function: leadSpinner_2
        function leadSpinner_2ValueChanged(app, event)
            app.plot_init()
            
            app.plot_positions_1()
            app.plot_positions_2()
            app.plot_positions_3()
            
            app.update_limits_x()
            app.update_limits_y()
        end

        % Value changed function: leadSpinner_3
        function leadSpinner_3ValueChanged(app, event)
            app.plot_init()
            
            app.plot_positions_1()
            app.plot_positions_2()
            app.plot_positions_3()
            
            app.update_limits_x()
            app.update_limits_y()
        end

        % Value changed function: leadSpinner_4
        function leadSpinner_4ValueChanged(app, event)
            app.plot_init()
            
            app.plot_positions_1()
            app.plot_positions_2()
            app.plot_positions_3()
            
            app.update_limits_x()
            app.update_limits_y()
        end

        % Value changed function: rangesecSpinner
        function rangesecSpinnerValueChanged(app, event)
            app.save_res()
            app.reload_data()
        end

        % Button pushed function: backupbButton
        function backupbButtonPushed(app, event)
            app.save_res()

            formatOut = 'HH_MM_SS__dd_mm_yyyy';
            backup_dir_curent= [app.backup_dir '/' datestr(now,formatOut)];
            mkdir(backup_dir_curent)
            
            for k = 1:length(app.output_names)
                try
                    copyfile(app.output_names{k},replace(app.output_names{k},app.output_dir,backup_dir_curent))
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.leftButton = uibutton(app.UIFigure, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = '<';
            app.rigthButton = uibutton(app.UIFigure, 'push');
            app.rigthButton.ButtonPushedFcn = createCallbackFcn(app, @rigthButtonPushed, true);
            app.rigthButton.Text = '>';
            app.curentnumEditField = uieditfield(app.UIFigure, 'numeric');
            app.curentnumEditField.Limits = [1 10];
            app.curentnumEditField.RoundFractionalValues = 'on';
            app.curentnumEditField.ValueChangedFcn = createCallbackFcn(app, @curentnumEditFieldValueChanged, true);
            app.curentnumEditField.Value = 1;
            app.backupbButton = uibutton(app.UIFigure, 'push');
            app.backupbButton.ButtonPushedFcn = createCallbackFcn(app, @backupbButtonPushed, true);
            app.backupbButton.Text = 'backup (b)';
            app.savesButton = uibutton(app.UIFigure, 'push');
            app.savesButton.Text = 'save (s)';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.Editable = 'off';
            app.labelEditFieldLabel = uilabel(app.UIFigure);
            app.labelEditFieldLabel.Text = 'label';
            app.labelEditField = uieditfield(app.UIFigure, 'text');
            app.netpredEditFieldLabel = uilabel(app.UIFigure);
            app.netpredEditFieldLabel.Text = 'net pred.';
            app.netpredEditField = uieditfield(app.UIFigure, 'text');
            app.leadSpinner_3 = uispinner(app.UIFigure);
            app.leadSpinner_3.Limits = [0 12];
            app.leadSpinner_3.ValueChangedFcn = createCallbackFcn(app, @leadSpinner_3ValueChanged, true);
            app.leadSpinner_3.Value = 3;
            app.leadSpinner_2 = uispinner(app.UIFigure);
            app.leadSpinner_2.Limits = [0 12];
            app.leadSpinner_2.ValueChangedFcn = createCallbackFcn(app, @leadSpinner_2ValueChanged, true);
            app.leadSpinner_2.Value = 2;
            app.leadSpinner_1 = uispinner(app.UIFigure);
            app.leadSpinner_1.Limits = [0 12];
            app.leadSpinner_1.ValueChangedFcn = createCallbackFcn(app, @leadSpinner_1ValueChanged, true);
            app.leadSpinner_1.Value = 1;
            app.patoButtonGroup = uibuttongroup(app.UIFigure);
            app.PVC1Button = uiradiobutton(app.patoButtonGroup);
            app.PVC1Button.Text = 'PVC (1)';
            app.PVC1Button.Value = true;
            app.PAC2Button = uiradiobutton(app.patoButtonGroup);
            app.PAC2Button.Text = 'PAC (2)';
            app.AF3Button = uiradiobutton(app.patoButtonGroup);
            app.AF3Button.Text = 'AF (3)';
            app.lead1zoompButton = uibutton(app.UIFigure, 'push');
            app.lead1zoompButton.ButtonPushedFcn = createCallbackFcn(app, @lead1zoompButtonPushed, true);
            app.lead1zoompButton.Text = '+';
            app.lead1zoommButton = uibutton(app.UIFigure, 'push');
            app.lead1zoommButton.ButtonPushedFcn = createCallbackFcn(app, @lead1zoommButtonPushed, true);
            app.lead1zoommButton.Text = '-';
            app.lead2zoompButton = uibutton(app.UIFigure, 'push');
            app.lead2zoompButton.ButtonPushedFcn = createCallbackFcn(app, @lead2zoompButtonPushed, true);
            app.lead2zoompButton.Text = '+';
            app.lead2zoommButton = uibutton(app.UIFigure, 'push');
            app.lead2zoommButton.ButtonPushedFcn = createCallbackFcn(app, @lead2zoommButtonPushed, true);
            app.lead2zoommButton.Text = '-';
            app.lead3zoompButton = uibutton(app.UIFigure, 'push');
            app.lead3zoompButton.ButtonPushedFcn = createCallbackFcn(app, @lead3zoompButtonPushed, true);
            app.lead3zoompButton.Text = '+';
            app.lead3zoommButton = uibutton(app.UIFigure, 'push');
            app.lead3zoommButton.ButtonPushedFcn = createCallbackFcn(app, @lead3zoommButtonPushed, true);
            app.lead3zoommButton.Text = '-';
            app.maxnumEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxnumEditField.Editable = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<<<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>>>>';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.leadSpinner_4 = uispinner(app.UIFigure);
            app.leadSpinner_4.Limits = [0 12];
            app.leadSpinner_4.ValueChangedFcn = createCallbackFcn(app, @leadSpinner_4ValueChanged, true);
            app.leadSpinner_4.Value = 3;
            app.lead4zoompButton = uibutton(app.UIFigure, 'push');
            app.lead4zoompButton.ButtonPushedFcn = createCallbackFcn(app, @lead4zoompButtonPushed, true);
            app.lead4zoompButton.Text = '+';
            app.lead4zoommButton = uibutton(app.UIFigure, 'push');
            app.lead4zoommButton.ButtonPushedFcn = createCallbackFcn(app, @lead4zoommButtonPushed, true);
            app.lead4zoommButton.Text = '-';
            app.rangesecSpinnerLabel = uilabel(app.UIFigure);
            app.rangesecSpinnerLabel.Text = 'range sec';
            app.rangesecSpinner = uispinner(app.UIFigure);
            app.rangesecSpinner.Limits = [1 100];
            app.rangesecSpinner.ValueChangedFcn = createCallbackFcn(app, @rangesecSpinnerValueChanged, true);
            app.rangesecSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PVC_PAC_AF_labeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end